classdef Example_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRCEyeTrackingExampleUIFigure   matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ConnectionSwitchLabel           matlab.ui.control.Label
        ConnectionSwitch                matlab.ui.control.Switch
        PortEditFieldLabel              matlab.ui.control.Label
        PortEditField                   matlab.ui.control.NumericEditField
        IpaddressEditFieldLabel         matlab.ui.control.Label
        IpaddressEditField              matlab.ui.control.EditField
        Lamp                            matlab.ui.control.Lamp
        Panel_2                         matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        CalibrationpointsDropDownLabel  matlab.ui.control.Label
        CalibrationpointsDropDown       matlab.ui.control.DropDown
        Lamp_4                          matlab.ui.control.Lamp
        Panel_3                         matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        StartButton_3                   matlab.ui.control.Button
        VideorecordingLabel             matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        PupilSizeEditFieldLabel         matlab.ui.control.Label
        PupilSizeEditField              matlab.ui.control.NumericEditField
        xEditField                      matlab.ui.control.NumericEditField
        smallLabel                      matlab.ui.control.Label
        bigLabel                        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        myTimer;
        myTimer2;
        calibration = 0;
        gaze = [1 0 0];
        size = [0 0];
    end
    
    methods (Access = private)
    
        function update(app,event)     %updates the gaze 
            if(app.calibration == 1)
               app.gaze = eye_get_gaze();
               if(app.gaze(1) == 0) 
                   plot(app.UIAxes,app.gaze(2),app.gaze(3),'o')               
                end
            end
        end
        
        function update2(app,event)    %updates the pupilsize (slower than update the gaze)
            app.size = eye_get_pupil_size();
            app.PupilSizeEditField.Value = app.size(1);
            app.xEditField.Value = app.size(2);
        end       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIAxes.XLim = [0 800];
             app.UIAxes.YLim = [0 600];
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
            if(value == "On") 
                %if eye_connect is successful, the timers and functions get enabled
                %to show the current state, lights on the gui get switched
                if(0 == eye_connect(app.IpaddressEditField.Value,app.PortEditField.Value))
                     app.Lamp.Color = [0.00,1.00,0.00];
                     app.myTimer = timer('Name','update','Period',0.01,'ExecutionMode','fixedSpacing','StartFcn',{@(~,~)app.update(app)},'timerFcn',{@(~,~)app.update(app)});
                     app.myTimer2 = timer('Name','update2','Period',1,'ExecutionMode','fixedSpacing','StartFcn',{@(~,~)app.update2(app)},'timerFcn',{@(~,~)app.update2(app)});
                     app.PupilSizeEditField.Enable = 'on';
                     app.xEditField.Enable = 'on';
                     eye_set_displaymode(800,600);
                     if(eye_get_status() == 2)
                        app.Lamp_4.Color = [0.00,1.00,0.00]; 
                        app.calibration = 1;
                     end
                     start(app.myTimer);      
                     start(app.myTimer2);
                else
                    app.ConnectionSwitch.Value = 'Off';
                    app.Lamp.Color = [1.00,0.00,0.00];
                    app.Lamp_4.Color = [1.00,0.00,0.00];
                    app.Lamp_3.Color = [1.00,0.00,0.00]; 
                    return;
                end 
            else
                app.Lamp.Color = [1.00,0.00,0.00];
                app.Lamp_4.Color = [1.00,0.00,0.00];
                app.Lamp_3.Color = [1.00,0.00,0.00];
                eye_stop_video_recording();
                if(app.myTimer ~= 0)
                    stop(app.myTimer);
                end
                if(app.myTimer2 ~= 0)    
                    stop(app.myTimer2);
                end
                eye_disconnect();
            end
        end

        % Close request function: MRCEyeTrackingExampleUIFigure
        function MRCEyeTrackingExampleUIFigureCloseRequest(app, event)
            if(app.myTimer ~= 0)
                stop(app.myTimer);
            end
            if(app.myTimer2 ~= 0)
                stop(app.myTimer2);
            end
            delete(app);
            eye_stop_video_recording();     %always stops video recording
            eye_disconnect(); 
        end

        % Value changed function: PupilSizeEditField
        function PupilSizeEditFieldValueChanged(app, event)
            value = app.PupilSizeEditField.Value;
            eyesize = eye_get_pupil_size();
            app.PupilSizeEditField.Value = eyesize(1);
            app.xEditField.Value = eyesize(2);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        if(app.ConnectionSwitch.Value == "On")
            %tries to start the calibration(only starts if a eye get detected once)
            PsychDefaultSetup(2);  
            screens = Screen('Screens');
            screenNumber = max(screens);
            white = WhiteIndex(screenNumber);
            [window, windowRect] = PsychImaging('OpenWindow', screenNumber, white,[250 350 1050 950]);   
            start = eye_start_calibrate(str2num(app.CalibrationpointsDropDown.Value));
            if(start == 0)              
                while((eye_get_status() ==   1) && (~KbCheck))
                    point = eye_get_calibration_point(); 
                    dotColor = rand(1,3);
                    dotSizePix = rand(1)*10  + 10;
                    Screen('DrawDots', window,[point(2) point(3)], dotSizePix, dotColor, [], 2);
                    Screen('Flip', window);
                end
                if(eye_get_status() == 2)
                    app.Lamp_4.Color = [0.00,1.00,0.00];
                    app.calibration = 1;
                else
                    app.Lamp_4.Color = [1.00,0.00,0.00];
                end
            else
                app.Lamp_4.Color = [1.00,0.00,0.00];
            end
        end
        sca;
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
        if(app.ConnectionSwitch.Value == "On")   
            app.Lamp_3.Color = [0.00,1.00,0.00];
            eye_start_video_recording();
        end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        if(app.ConnectionSwitch.Value == "On")  
            app.Lamp_3.Color = [1.00,0.00,0.00];
            eye_stop_video_recording();
        end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRCEyeTrackingExampleUIFigure = uifigure;
            app.MRCEyeTrackingExampleUIFigure.Name = 'MRC Eye-Tracking Example';
            app.MRCEyeTrackingExampleUIFigure.CloseRequestFcn = createCallbackFcn(app, @MRCEyeTrackingExampleUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MRCEyeTrackingExampleUIFigure);
            app.ConnectionSwitchLabel = uilabel(app.Panel);
            app.ConnectionSwitchLabel.Text = 'Connection';
            app.ConnectionSwitch = uiswitch(app.Panel, 'slider');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.PortEditFieldLabel = uilabel(app.Panel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.Panel, 'numeric');
            app.PortEditField.Limits = [1024 55635];
            app.PortEditField.Value = 5257;
            app.IpaddressEditFieldLabel = uilabel(app.Panel);
            app.IpaddressEditFieldLabel.Text = 'Ip address';
            app.IpaddressEditField = uieditfield(app.Panel, 'text');
            app.IpaddressEditField.Value = 'localhost';
            app.Lamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.MRCEyeTrackingExampleUIFigure);
            app.StartButton = uibutton(app.Panel_2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status';
            app.CalibrationpointsDropDownLabel = uilabel(app.Panel_2);
            app.CalibrationpointsDropDownLabel.Text = 'Calibrationpoints';
            app.CalibrationpointsDropDown = uidropdown(app.Panel_2);
            app.CalibrationpointsDropDown.Items = {'9', '13', '16', '17'};
            app.CalibrationpointsDropDown.Value = '9';
            app.Lamp_4 = uilamp(app.Panel_2);
            app.Panel_3 = uipanel(app.MRCEyeTrackingExampleUIFigure);
            app.StopButton = uibutton(app.Panel_3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton_3 = uibutton(app.Panel_3, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.VideorecordingLabel = uilabel(app.Panel_3);
            app.VideorecordingLabel.Text = 'Video recording';
            app.Lamp_3 = uilamp(app.Panel_3);
            app.PupilSizeEditFieldLabel = uilabel(app.MRCEyeTrackingExampleUIFigure);
            app.PupilSizeEditFieldLabel.Text = 'Pupil Size';
            app.PupilSizeEditField = uieditfield(app.MRCEyeTrackingExampleUIFigure, 'numeric');
            app.PupilSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PupilSizeEditFieldValueChanged, true);
            app.PupilSizeEditField.Limits = [0 Inf];
            app.PupilSizeEditField.Editable = 'off';
            app.PupilSizeEditField.Enable = 'off';
            app.xEditField = uieditfield(app.MRCEyeTrackingExampleUIFigure, 'numeric');
            app.xEditField.Limits = [0 Inf];
            app.xEditField.Editable = 'off';
            app.xEditField.Enable = 'off';
            app.smallLabel = uilabel(app.MRCEyeTrackingExampleUIFigure);
            app.smallLabel.Text = 'small';
            app.bigLabel = uilabel(app.MRCEyeTrackingExampleUIFigure);
            app.bigLabel.Text = 'big';
            app.UIAxes = uiaxes(app.MRCEyeTrackingExampleUIFigure);
            title(app.UIAxes, 'Gaze')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Example_3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRCEyeTrackingExampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRCEyeTrackingExampleUIFigure)
        end
    end
end